classdef dicom_app_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CIVMU24DICOMConverterUIFigure   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StudyDataTab                    matlab.ui.container.Tab
        ClinicalTrialProtocolNameEditFieldLabel  matlab.ui.control.Label
        ClinicalTrialProtocolNameEditField  matlab.ui.control.EditField
        ClinicalTrialIDEditFieldLabel   matlab.ui.control.Label
        ClinicalTrialIDEditField        matlab.ui.control.EditField
        StudyDateDatePickerLabel        matlab.ui.control.Label
        StudyDateDatePicker             matlab.ui.control.DatePicker
        ProcedureDescriptionLabel       matlab.ui.control.Label
        ProcedureDescriptionEditField   matlab.ui.control.EditField
        ProtocolNameEditFieldLabel      matlab.ui.control.Label
        ProtocolNameEditField           matlab.ui.control.EditField
        StudyDescriptionEditFieldLabel  matlab.ui.control.Label
        StudyDescriptionEditField       matlab.ui.control.EditField
        StudyIDEditFieldLabel           matlab.ui.control.Label
        StudyIDEditField                matlab.ui.control.EditField
        PatientSpeciesEditFieldLabel    matlab.ui.control.Label
        PatientSpeciesEditField         matlab.ui.control.EditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        PatientIDEditField              matlab.ui.control.EditField
        PatientDOBEditFieldLabel        matlab.ui.control.Label
        PatientDOBEditField             matlab.ui.control.EditField
        PatientSexEditFieldLabel        matlab.ui.control.Label
        PatientSexEditField             matlab.ui.control.EditField
        PatientWeightEditFieldLabel     matlab.ui.control.Label
        PatientWeightEditField          matlab.ui.control.EditField
        BodyPartExaminedEditFieldLabel  matlab.ui.control.Label
        BodyPartExaminedEditField       matlab.ui.control.EditField
        PatientPositionEditFieldLabel   matlab.ui.control.Label
        PatientPositionEditField        matlab.ui.control.EditField
        SeriesDescriptionEditField_2Label  matlab.ui.control.Label
        SeriesDescriptionEditField      matlab.ui.control.EditField
        AcquisitionNumberEditFieldLabel  matlab.ui.control.Label
        AcquisitionNumberEditField      matlab.ui.control.EditField
        StudyTimeEditFieldLabel         matlab.ui.control.Label
        StudyTimeEditField              matlab.ui.control.EditField
        AcquisitiondetailsTab           matlab.ui.container.Tab
        ManufacturerEditFieldLabel      matlab.ui.control.Label
        ManufacturerEditField           matlab.ui.control.EditField
        StationNameEditFieldLabel       matlab.ui.control.Label
        StationNameEditField            matlab.ui.control.EditField
        ImageTypeEditFieldLabel         matlab.ui.control.Label
        ImageTypeEditField              matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        ModelNameEditField              matlab.ui.control.EditField
        SoftwareNameEditFieldLabel      matlab.ui.control.Label
        SoftwareNameEditField           matlab.ui.control.EditField
        DistanceSourcetoDetectorEditFieldLabel  matlab.ui.control.Label
        DistanceSourcetoDetectorEditField  matlab.ui.control.EditField
        DistanceSourcetoPatientEditFieldLabel  matlab.ui.control.Label
        DistanceSourcetoPatientEditField  matlab.ui.control.EditField
        GantryTiltEditFieldLabel        matlab.ui.control.Label
        GantryTiltEditField             matlab.ui.control.EditField
        TableHeightEditFieldLabel       matlab.ui.control.Label
        TableHeightEditField            matlab.ui.control.EditField
        RotationDirectionEditFieldLabel  matlab.ui.control.Label
        RotationDirectionEditField      matlab.ui.control.EditField
        ExposureTimeEditFieldLabel      matlab.ui.control.Label
        ExposureTimeEditField           matlab.ui.control.EditField
        XrayCurrentEditFieldLabel       matlab.ui.control.Label
        XrayCurrentEditField            matlab.ui.control.EditField
        ExposureEditFieldLabel          matlab.ui.control.Label
        ExposureEditField               matlab.ui.control.EditField
        FocalSpotEditFieldLabel         matlab.ui.control.Label
        FocalSpotEditField              matlab.ui.control.EditField
        GeneratorPowerEditFieldLabel    matlab.ui.control.Label
        GeneratorPowerEditField         matlab.ui.control.EditField
        LastCalibratedEditFieldLabel    matlab.ui.control.Label
        LastCalibratedEditField         matlab.ui.control.EditField
        XrayVoltageEditFieldLabel       matlab.ui.control.Label
        PixelSpacingEditFieldLabel      matlab.ui.control.Label
        PixelSpacingEditField           matlab.ui.control.EditField
        SliceThicknessEditFieldLabel    matlab.ui.control.Label
        SliceThicknessEditField         matlab.ui.control.EditField
        ImagePathsTab                   matlab.ui.container.Tab
        NiftiImagefileEditFieldLabel    matlab.ui.control.Label
        NiftiImagefileEditField         matlab.ui.control.EditField
        OutputDICOMDirectoryEditFieldLabel  matlab.ui.control.Label
        OutputDICOMDirectoryEditField   matlab.ui.control.EditField
        MakeDicomsButton                matlab.ui.control.Button
        XrayVoltageEditField            matlab.ui.control.EditField
        ModalityEditFieldLabel          matlab.ui.control.Label
        ModalityEditField               matlab.ui.control.EditField
        SaveSettingsButton              matlab.ui.control.Button
        LoadSettingsButton              matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function dicom_header = update_fields(app, header_file)
            
            % Update paths
            update_paths;
            
            % Load the header file
            dicom_header = loadjson(header_file);
            
            %% Update GUI fields %%
            
            % Study data
            app.ClinicalTrialProtocolNameEditField.Value = dicom_header.ClinicalTrialProtocolName;
            app.ClinicalTrialIDEditField.Value = dicom_header.ClinicalTrialProtocolID;
            app.StudyDateDatePicker.Value = datetime([dicom_header.StudyDate.Year, dicom_header.StudyDate.Month, dicom_header.StudyDate.Day]);
            app.ProcedureDescriptionEditField.Value = dicom_header.ProcedureDescription;
            app.ProtocolNameEditField.Value = dicom_header.ProtocolName;
            app.StudyDescriptionEditField.Value = dicom_header.StudyDescription;
            app.StudyIDEditField.Value = dicom_header.StudyID;
            app.PatientSpeciesEditField.Value = dicom_header.PatientSpecies;
            app.PatientIDEditField.Value = dicom_header.PatientID;
            app.PatientDOBEditField.Value = dicom_header.PatientBirthDate;
            app.PatientSexEditField.Value = dicom_header.PatientSex;
            app.PatientWeightEditField.Value = dicom_header.PatientWeight;
            app.BodyPartExaminedEditField.Value = dicom_header.BodyPartExamined;
            app.PatientPositionEditField.Value = dicom_header.PatientPosition;
            app.SeriesDescriptionEditField.Value = dicom_header.SeriesDescription;
            app.AcquisitionNumberEditField.Value = dicom_header.AcquisitionNumber;
            app.StudyTimeEditField.Value = dicom_header.StudyTime;
            
            % Acquisition details
            app.ModalityEditField.Value = dicom_header.Modality;
            app.ManufacturerEditField.Value = dicom_header.Manufacturer;
            app.StationNameEditField.Value = dicom_header.StationName;
            app.ImageTypeEditField.Value = dicom_header.ImageType;
            app.ModelNameEditField.Value = dicom_header.ManufacturerModelName;
            app.SoftwareNameEditField.Value = dicom_header.SoftwareVersion;
            app.DistanceSourcetoDetectorEditField.Value = dicom_header.DistanceSourceToDetector;
            app.DistanceSourcetoPatientEditField.Value = dicom_header.DistanceSourceToPatient;
            app.GantryTiltEditField.Value = dicom_header.GantryDetectorTilt;
            app.TableHeightEditField.Value = dicom_header.TableHeight;
            app.RotationDirectionEditField.Value = dicom_header.RotationDirection;
            app.ExposureTimeEditField.Value = dicom_header.ExposureTime;
            app.XrayCurrentEditField.Value = dicom_header.XrayTubeCurrent;
            app.ExposureEditField.Value = dicom_header.Exposure;
            app.FocalSpotEditField.Value = dicom_header.FilterType;
            app.GeneratorPowerEditField.Value = dicom_header.GeneratorPower;
            app.LastCalibratedEditField.Value = dicom_header.DateOfLastCalibration;
            app.XrayVoltageEditField.Value = dicom_header.KVP;
            app.PixelSpacingEditField.Value = dicom_header.PixelSpacing;
            app.SliceThicknessEditField.Value = dicom_header.SliceThickness;
            app.FocalSpotEditField.Value = dicom_header.FocalSpot;
            
            % Image paths
            app.NiftiImagefileEditField.Value = dicom_header.image_path;
            app.OutputDICOMDirectoryEditField.Value = dicom_header.output_dir;            
            
        end
        
        
        function dicom_header = get_fields(app)
            
            % Study data
            dicom_header.ClinicalTrialProtocolName = app.ClinicalTrialProtocolNameEditField.Value;
            dicom_header.ClinicalTrialProtocolID = app.ClinicalTrialIDEditField.Value;
            dicom_header.StudyDate = app.StudyDateDatePicker.Value;
            dicom_header.ProcedureDescription = app.ProcedureDescriptionEditField.Value;
            dicom_header.ProtocolName = app.ProtocolNameEditField.Value;
            dicom_header.StudyDescription = app.StudyDescriptionEditField.Value;
            dicom_header.StudyID = app.StudyIDEditField.Value;
            dicom_header.PatientSpecies = app.PatientSpeciesEditField.Value;
            dicom_header.PatientID = app.PatientIDEditField.Value;
            dicom_header.PatientBirthDate = app.PatientDOBEditField.Value;
            dicom_header.PatientSex = app.PatientSexEditField.Value;
            dicom_header.PatientWeight = app.PatientWeightEditField.Value;
            dicom_header.BodyPartExamined = app.BodyPartExaminedEditField.Value;
            dicom_header.PatientPosition = app.PatientPositionEditField.Value;
            dicom_header.SeriesDescription = app.SeriesDescriptionEditField.Value;
            dicom_header.AcquisitionNumber = app.AcquisitionNumberEditField.Value;
            dicom_header.StudyTime = app.StudyTimeEditField.Value;
            
            % Acquisition details
            dicom_header.Modality = app.ModalityEditField.Value;
            dicom_header.Manufacturer = app.ManufacturerEditField.Value;
            dicom_header.StationName = app.StationNameEditField.Value;
            dicom_header.ImageType = app.ImageTypeEditField.Value;
            dicom_header.ManufacturerModelName = app.ModelNameEditField.Value;
            dicom_header.SoftwareVersion = app.SoftwareNameEditField.Value;
            dicom_header.DistanceSourceToDetector = app.DistanceSourcetoDetectorEditField.Value;
            dicom_header.DistanceSourceToPatient = app.DistanceSourcetoPatientEditField.Value;
            dicom_header.GantryDetectorTilt = app.GantryTiltEditField.Value;
            dicom_header.TableHeight = app.TableHeightEditField.Value;
            dicom_header.RotationDirection = app.RotationDirectionEditField.Value;
            dicom_header.ExposureTime = app.ExposureTimeEditField.Value;
            dicom_header.XrayTubeCurrent = app.XrayCurrentEditField.Value;
            dicom_header.Exposure = app.ExposureEditField.Value;
            dicom_header.FilterType = app.FocalSpotEditField.Value;
            dicom_header.GeneratorPower = app.GeneratorPowerEditField.Value;
            dicom_header.DateOfLastCalibration = app.LastCalibratedEditField.Value;
            dicom_header.KVP = app.XrayVoltageEditField.Value;
            dicom_header.PixelSpacing = app.PixelSpacingEditField.Value;
            dicom_header.SliceThickness = app.SliceThicknessEditField.Value;
            dicom_header.FocalSpot = app.FocalSpotEditField.Value;
            
            % Image paths
            dicom_header.image_path = app.NiftiImagefileEditField.Value;
            dicom_header.output_dir = app.OutputDICOMDirectoryEditField.Value;   
            
        end
        
        function save_fields(app, dicom_header, file_name)
            
            % Save JSON file with DICOM fields
            savejson('', dicom_header, file_name);
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Load previous session and update fields
            [current_dir, ~] = fileparts(mfilename('fullpath'));
            save_file = fullfile(current_dir, 'gui_data.json');
            dicom_header = update_fields(app, save_file);            
            
            
        end

        % Button pushed function: MakeDicomsButton
        function MakeDicomsButtonPushed(app, event)
            
            % Get field values
            dicom_header = get_fields(app);
            
            % Generate save file
            [current_dir, ~] = fileparts(mfilename('fullpath'));
            save_file = fullfile(current_dir, 'gui_data.json');
            
            % Save the current fields
            save_fields(app, dicom_header, save_file);
            
            % Make DICOMs
            write_dicoms(dicom_header.image_path, save_file, dicom_header.output_dir);
            
            
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            
            % Get current fields
            dicom_header = get_fields(app);
            
            % Set up save path
            save_folder = mfilename('fullfile');
            [save_folder, ~] = fileparts(save_folder);
            save_folder = fullfile(save_folder, 'header_templates');
            if ~exist(save_folder, 'dir'); mkdir(save_folder); end
            
            filter = '*.json';
            title = 'Save GUI settings';
            [file, path] = uiputfile(filter, title, save_folder);
            file_name = fullfile(path, file);
            
            % Save fields
            save_fields(app, dicom_header, file_name);
            
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            
            % Set up save path
            save_folder = mfilename('fullfile');
            [save_folder, ~] = fileparts(save_folder);
            save_folder = fullfile(save_folder, 'header_templates');
            if ~exist(save_folder, 'dir'); mkdir(save_folder); end
            
            filter = '*.json';
            title = 'Load GUI settings';
            [file, path] = uigetfile(filter, title, save_folder);
            file_name = fullfile(path, file);
            
            % Update fields from file
            update_fields(app, file_name);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CIVMU24DICOMConverterUIFigure = uifigure;
            app.CIVMU24DICOMConverterUIFigure.Name = 'CIVM: U24 DICOM Converter';
            app.TabGroup = uitabgroup(app.CIVMU24DICOMConverterUIFigure);
            app.StudyDataTab = uitab(app.TabGroup);
            app.StudyDataTab.Title = 'Study Data';
            app.ClinicalTrialProtocolNameEditFieldLabel = uilabel(app.StudyDataTab);
            app.ClinicalTrialProtocolNameEditFieldLabel.Text = {'Clinical Trial'; 'Protocol Name'};
            app.ClinicalTrialProtocolNameEditField = uieditfield(app.StudyDataTab, 'text');
            app.ClinicalTrialIDEditFieldLabel = uilabel(app.StudyDataTab);
            app.ClinicalTrialIDEditFieldLabel.Text = 'Clinical Trial ID';
            app.ClinicalTrialIDEditField = uieditfield(app.StudyDataTab, 'text');
            app.StudyDateDatePickerLabel = uilabel(app.StudyDataTab);
            app.StudyDateDatePickerLabel.Text = 'Study Date';
            app.StudyDateDatePicker = uidatepicker(app.StudyDataTab);
            app.ProcedureDescriptionLabel = uilabel(app.StudyDataTab);
            app.ProcedureDescriptionLabel.Text = 'ProcedureDescription';
            app.ProcedureDescriptionEditField = uieditfield(app.StudyDataTab, 'text');
            app.ProtocolNameEditFieldLabel = uilabel(app.StudyDataTab);
            app.ProtocolNameEditFieldLabel.Text = 'Protocol Name';
            app.ProtocolNameEditField = uieditfield(app.StudyDataTab, 'text');
            app.StudyDescriptionEditFieldLabel = uilabel(app.StudyDataTab);
            app.StudyDescriptionEditFieldLabel.Text = 'Study Description';
            app.StudyDescriptionEditField = uieditfield(app.StudyDataTab, 'text');
            app.StudyIDEditFieldLabel = uilabel(app.StudyDataTab);
            app.StudyIDEditFieldLabel.Text = 'Study ID';
            app.StudyIDEditField = uieditfield(app.StudyDataTab, 'text');
            app.PatientSpeciesEditFieldLabel = uilabel(app.StudyDataTab);
            app.PatientSpeciesEditFieldLabel.Text = 'Patient Species';
            app.PatientSpeciesEditField = uieditfield(app.StudyDataTab, 'text');
            app.PatientIDEditFieldLabel = uilabel(app.StudyDataTab);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.StudyDataTab, 'text');
            app.PatientDOBEditFieldLabel = uilabel(app.StudyDataTab);
            app.PatientDOBEditFieldLabel.Text = 'Patient DOB';
            app.PatientDOBEditField = uieditfield(app.StudyDataTab, 'text');
            app.PatientSexEditFieldLabel = uilabel(app.StudyDataTab);
            app.PatientSexEditFieldLabel.Text = 'Patient Sex';
            app.PatientSexEditField = uieditfield(app.StudyDataTab, 'text');
            app.PatientWeightEditFieldLabel = uilabel(app.StudyDataTab);
            app.PatientWeightEditFieldLabel.Text = 'Patient Weight';
            app.PatientWeightEditField = uieditfield(app.StudyDataTab, 'text');
            app.BodyPartExaminedEditFieldLabel = uilabel(app.StudyDataTab);
            app.BodyPartExaminedEditFieldLabel.Text = 'Body Part Examined';
            app.BodyPartExaminedEditField = uieditfield(app.StudyDataTab, 'text');
            app.PatientPositionEditFieldLabel = uilabel(app.StudyDataTab);
            app.PatientPositionEditFieldLabel.Text = 'Patient Position';
            app.PatientPositionEditField = uieditfield(app.StudyDataTab, 'text');
            app.SeriesDescriptionEditField_2Label = uilabel(app.StudyDataTab);
            app.SeriesDescriptionEditField_2Label.Text = 'Series Description';
            app.SeriesDescriptionEditField = uieditfield(app.StudyDataTab, 'text');
            app.AcquisitionNumberEditFieldLabel = uilabel(app.StudyDataTab);
            app.AcquisitionNumberEditFieldLabel.Text = 'Acquisition Number';
            app.AcquisitionNumberEditField = uieditfield(app.StudyDataTab, 'text');
            app.StudyTimeEditFieldLabel = uilabel(app.StudyDataTab);
            app.StudyTimeEditFieldLabel.Text = 'Study Time';
            app.StudyTimeEditField = uieditfield(app.StudyDataTab, 'text');
            app.AcquisitiondetailsTab = uitab(app.TabGroup);
            app.AcquisitiondetailsTab.Title = 'Acquisition details';
            app.ModalityEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.ModalityEditFieldLabel.Text = 'Modality';
            app.ModalityEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.ManufacturerEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.ManufacturerEditFieldLabel.Text = 'Manufacturer';
            app.ManufacturerEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.StationNameEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.StationNameEditFieldLabel.Text = 'Station Name';
            app.StationNameEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.ImageTypeEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.ImageTypeEditFieldLabel.Text = 'Image Type';
            app.ImageTypeEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.ModelNameEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.ModelNameEditFieldLabel.Text = 'Model Name';
            app.ModelNameEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.SoftwareNameEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.SoftwareNameEditFieldLabel.Text = 'SoftwareName';
            app.SoftwareNameEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.DistanceSourcetoDetectorEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.DistanceSourcetoDetectorEditFieldLabel.Text = {'Distance'; 'Source to Detector'};
            app.DistanceSourcetoDetectorEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.DistanceSourcetoPatientEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.DistanceSourcetoPatientEditFieldLabel.Text = {'Distance'; 'Source to Patient'};
            app.DistanceSourcetoPatientEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.GantryTiltEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.GantryTiltEditFieldLabel.Text = 'Gantry Tilt';
            app.GantryTiltEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.TableHeightEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.TableHeightEditFieldLabel.Text = 'Table Height';
            app.TableHeightEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.RotationDirectionEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.RotationDirectionEditFieldLabel.Text = 'Rotation Direction';
            app.RotationDirectionEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.ExposureTimeEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.XrayCurrentEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.XrayCurrentEditFieldLabel.Text = 'Xray Current';
            app.XrayCurrentEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.ExposureEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.FocalSpotEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.FocalSpotEditFieldLabel.Text = 'Focal Spot';
            app.FocalSpotEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.GeneratorPowerEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.GeneratorPowerEditFieldLabel.Text = 'Generator Power';
            app.GeneratorPowerEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.LastCalibratedEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.LastCalibratedEditFieldLabel.Text = 'Last Calibrated';
            app.LastCalibratedEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.XrayVoltageEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.XrayVoltageEditFieldLabel.Text = 'Xray Voltage';
            app.XrayVoltageEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.PixelSpacingEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.PixelSpacingEditFieldLabel.Text = 'Pixel Spacing';
            app.PixelSpacingEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.SliceThicknessEditFieldLabel = uilabel(app.AcquisitiondetailsTab);
            app.SliceThicknessEditFieldLabel.Text = 'Slice Thickness';
            app.SliceThicknessEditField = uieditfield(app.AcquisitiondetailsTab, 'text');
            app.ImagePathsTab = uitab(app.TabGroup);
            app.ImagePathsTab.Title = 'Image Paths';
            app.NiftiImagefileEditFieldLabel = uilabel(app.ImagePathsTab);
            app.NiftiImagefileEditFieldLabel.Text = 'Nifti Image file';
            app.NiftiImagefileEditField = uieditfield(app.ImagePathsTab, 'text');
            app.OutputDICOMDirectoryEditFieldLabel = uilabel(app.ImagePathsTab);
            app.OutputDICOMDirectoryEditFieldLabel.Text = {'Output DICOM'; 'Directory'};
            app.OutputDICOMDirectoryEditField = uieditfield(app.ImagePathsTab, 'text');
            app.MakeDicomsButton = uibutton(app.CIVMU24DICOMConverterUIFigure, 'push');
            app.MakeDicomsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeDicomsButtonPushed, true);
            app.MakeDicomsButton.Text = 'Make Dicoms';
            app.SaveSettingsButton = uibutton(app.CIVMU24DICOMConverterUIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.CIVMU24DICOMConverterUIFigure, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dicom_app_v1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CIVMU24DICOMConverterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CIVMU24DICOMConverterUIFigure)
        end
    end
end